classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NotchrejectInputPanel           matlab.ui.container.Panel
        NotchesTextAreaLabel            matlab.ui.control.Label
        NotchesTextArea                 matlab.ui.control.TextArea
        Notch_MagnitudeSpectrum         matlab.ui.control.UIAxes
        BandrejectInputPanel            matlab.ui.container.Panel
        BR_nEditField                   matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        BR_D0EditField                  matlab.ui.control.NumericEditField
        D0EditFieldLabel                matlab.ui.control.Label
        BR_WEditField                   matlab.ui.control.NumericEditField
        WEditFieldLabel                 matlab.ui.control.Label
        BandrejectMethodDropDown        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        BR_MagnitudeSpectrum            matlab.ui.control.UIAxes
        BrowseFileButton                matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        RestorationMethodDropDown       matlab.ui.control.DropDown
        RestorationMethodDropDownLabel  matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        InputImage                      matlab.ui.control.Image
        PanelTemplate                   matlab.ui.container.Panel
        OutputHistAxes                  matlab.ui.control.UIAxes
        OutputImage                     matlab.ui.control.UIAxes
        InputHistAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage_val
        OutputImage_val
        SpecImage_val
    end
    
    methods (Access = private)
        
        function SetMagnitudeSpectrum(~, axes, F2)
            imagesc(axes, F2);
            colormap(axes, "gray");
            axes.XLim = [0, size(F2, 2)];
            axes.YLim = [0, size(F2, 1)];
        end
        
        function color_name = GetBarColor(~, idx)
            if idx == 1
                color_name = 'red';
            elseif idx == 2
                color_name = 'green';
            else
                color_name = 'blue';
            end
        end
        
        
        function ChangeWindowWidth(app, newWidth)
            app.UIFigure.Position = [100 100 newWidth 640];
        end
        
        function ResetFigure(app)
            app.BandrejectInputPanel.Visible = "off";
            app.NotchrejectInputPanel.Visible = "off";
        end
        
        
        function UpdateParameterSpectrum(app, event)
            if strcmp(app.RestorationMethodDropDown.Value, "Bandreject Filter") 
                BandrejectMethodDropDownValueChanged(app, event);
            elseif strcmp(app.RestorationMethodDropDown.Value, "Notchreject Filter") 
                NotchesTextAreaValueChanged(app, event);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputFileEditField
        function InputFileEditFieldValueChanged(app, event)
            value = app.InputFileEditField.Value;
            app.InputImage.ImageSource = value;
            app.InputImage_val = imread(value);
            F = getSpectrum(app.InputImage_val);
            SetMagnitudeSpectrum(app, app.InputHistAxes, F);
            UpdateParameterSpectrum(app, event);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)

            [parentFolder, filename, fileExt] = fileparts(app.InputFileEditField.Value);
            imwrite(app.OutputImage_val, parentFolder + "/" + filename + "_01" + fileExt);
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('../test_case/*.*');
            if isequal(filename, 0)
                
            else
                figure(app.UIFigure);
                app.InputFileEditField.Value = fullfile(pathname, filename);
                InputFileEditFieldValueChanged(app, event);
                
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.RestorationMethodDropDown.Value;
            if strcmp(method, "Bandreject Filter") 
                D0 = app.BR_D0EditField.Value;
                W = app.BR_WEditField.Value;
                n = app.BR_nEditField.Value;
                bandreject_method = app.BandrejectMethodDropDown.Value;
                if strcmp(bandreject_method, "Ideal")
                    app.OutputImage_val = idealBandreject(app.InputImage_val, D0, W);
                elseif strcmp(bandreject_method, "Gaussian")
                    app.OutputImage_val = gaussianBandreject(app.InputImage_val, D0, W);
                elseif strcmp(bandreject_method, "Butterworth")
                    app.OutputImage_val = butterworthBandreject(app.InputImage_val, D0, W, n);
                end
                SetMagnitudeSpectrum(app, app.OutputHistAxes, getSpectrum(app.OutputImage_val));
            elseif strcmp(method, "Notchreject Filter")
                value = string(app.NotchesTextArea.Value);
                notches = zeros(2,2,0);
                notches_str = splitlines(value);
                for n = 1:length(notches_str)
                    
                    notches = cat(3, notches, str2num(notches_str(n)));
                end
                app.OutputImage_val = notchreject(app.InputImage_val, notches);
                SetMagnitudeSpectrum(app, app.OutputHistAxes, getSpectrum(app.OutputImage_val))
            end
            imshow(app.OutputImage_val, 'Parent', app.OutputImage);
            app.OutputImage.XLim = [0, size(app.OutputImage_val, 2)];
            app.OutputImage.YLim = [0, size(app.OutputImage_val, 1)];
        end

        % Value changed function: RestorationMethodDropDown
        function RestorationMethodDropDownValueChanged(app, event)
            ResetFigure(app);
            method = app.RestorationMethodDropDown.Value;
            if strcmp(method, "Bandreject Filter") 
                app.BandrejectInputPanel.Visible = "on";
            elseif strcmp(method, "Notchreject Filter") 
                app.NotchrejectInputPanel.Visible = "on";
            end
            UpdateParameterSpectrum(app, event);
        end

        % Value changed function: BR_D0EditField, BR_WEditField, 
        % ...and 2 other components
        function BandrejectMethodDropDownValueChanged(app, event)
            F = getSpectrum(app.InputImage_val);
            mode = app.BandrejectMethodDropDown.Value;
            D0 = app.BR_D0EditField.Value;
            W = app.BR_WEditField.Value;
            n = app.BR_nEditField.Value;
            if strcmp(mode, "Ideal")
                SetMagnitudeSpectrum(app, app.BR_MagnitudeSpectrum, idealBandrejectF(F, D0, W));
            elseif strcmp(mode, "Gaussian")
                SetMagnitudeSpectrum(app, app.BR_MagnitudeSpectrum, gaussianBandrejectF(F, D0, W));
            elseif strcmp(mode, "Butterworth")
                SetMagnitudeSpectrum(app, app.BR_MagnitudeSpectrum, butterworthBandrejectF(F, D0, W, n));
            end
        end

        % Value changed function: NotchesTextArea
        function NotchesTextAreaValueChanged(app, event)
            F = getSpectrum(app.InputImage_val);
            value = string(app.NotchesTextArea.Value);
            disp(value)
            notches = zeros(2,2,0);
            notches_str = splitlines(value);
            for n = 1:length(notches_str)
                
                notches = cat(3, notches, str2num(notches_str(n)));
            end

            SetMagnitudeSpectrum(app, app.Notch_MagnitudeSpectrum, notchrejectF(F, notches))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputHistAxes = uiaxes(app.UIFigure);
            title(app.InputHistAxes, 'Input Image Magnitude Spectrum')
            xlabel(app.InputHistAxes, 'X')
            ylabel(app.InputHistAxes, 'Y')
            zlabel(app.InputHistAxes, 'Z')
            app.OutputImage = uiaxes(app.UIFigure);
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.OutputHistAxes = uiaxes(app.UIFigure);
            title(app.OutputHistAxes, 'Output Image Magnitude Spectrum')
            xlabel(app.OutputHistAxes, 'X')
            ylabel(app.OutputHistAxes, 'Y')
            zlabel(app.OutputHistAxes, 'Z')
            app.PanelTemplate = uipanel(app.UIFigure);
            app.PanelTemplate.Visible = 'off';
            app.InputImage = uiimage(app.UIFigure);
            app.InputFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputFileEditFieldValueChanged, true);
            app.RestorationMethodDropDownLabel = uilabel(app.UIFigure);
            app.RestorationMethodDropDownLabel.Text = 'Restoration Method';
            app.RestorationMethodDropDown = uidropdown(app.UIFigure);
            app.RestorationMethodDropDown.Items = {'None', 'Bandreject Filter', 'Notchreject Filter'};
            app.RestorationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RestorationMethodDropDownValueChanged, true);
            app.RestorationMethodDropDown.Value = 'None';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.BrowseFileButton = uibutton(app.UIFigure, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.BandrejectInputPanel = uipanel(app.UIFigure);
            app.BandrejectInputPanel.Visible = 'off';
            app.BR_MagnitudeSpectrum = uiaxes(app.BandrejectInputPanel);
            xlabel(app.BR_MagnitudeSpectrum, 'X')
            ylabel(app.BR_MagnitudeSpectrum, 'Y')
            zlabel(app.BR_MagnitudeSpectrum, 'Z')
            app.DropDownLabel = uilabel(app.BandrejectInputPanel);
            app.DropDownLabel.Text = 'Bandreject Method';
            app.BandrejectMethodDropDown = uidropdown(app.BandrejectInputPanel);
            app.BandrejectMethodDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.BandrejectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BandrejectMethodDropDownValueChanged, true);
            app.BandrejectMethodDropDown.Value = 'Ideal';
            app.WEditFieldLabel = uilabel(app.BandrejectInputPanel);
            app.WEditFieldLabel.Text = 'W';
            app.BR_WEditField = uieditfield(app.BandrejectInputPanel, 'numeric');
            app.BR_WEditField.ValueChangedFcn = createCallbackFcn(app, @BandrejectMethodDropDownValueChanged, true);
            app.D0EditFieldLabel = uilabel(app.BandrejectInputPanel);
            app.D0EditFieldLabel.Text = 'D0';
            app.BR_D0EditField = uieditfield(app.BandrejectInputPanel, 'numeric');
            app.BR_D0EditField.ValueChangedFcn = createCallbackFcn(app, @BandrejectMethodDropDownValueChanged, true);
            app.nEditFieldLabel = uilabel(app.BandrejectInputPanel);
            app.nEditFieldLabel.Text = 'n';
            app.BR_nEditField = uieditfield(app.BandrejectInputPanel, 'numeric');
            app.BR_nEditField.ValueChangedFcn = createCallbackFcn(app, @BandrejectMethodDropDownValueChanged, true);
            app.NotchrejectInputPanel = uipanel(app.UIFigure);
            app.NotchrejectInputPanel.Visible = 'off';
            app.Notch_MagnitudeSpectrum = uiaxes(app.NotchrejectInputPanel);
            xlabel(app.Notch_MagnitudeSpectrum, 'X')
            ylabel(app.Notch_MagnitudeSpectrum, 'Y')
            zlabel(app.Notch_MagnitudeSpectrum, 'Z')
            app.NotchesTextArea = uitextarea(app.NotchrejectInputPanel);
            app.NotchesTextArea.ValueChangedFcn = createCallbackFcn(app, @NotchesTextAreaValueChanged, true);
            app.NotchesTextAreaLabel = uilabel(app.NotchrejectInputPanel);
            app.NotchesTextAreaLabel.Text = 'Notches';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end